classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FanDropDown         matlab.ui.control.DropDown
        FanDropDownLabel    matlab.ui.control.Label
        settingsButton      matlab.ui.control.Button
        ThemeDropDown       matlab.ui.control.DropDown
        ThemeDropDownLabel  matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        F01_air_speed_mps; % exactly what it says it is
        F02_air_speed_mps; % exactly what it says it is
        F03_air_speed_mps; % exactly what it says it is
        F04_air_speed_mps; % exactly what it says it is
        F05_air_speed_mps; % exactly what it says it is
        F06_air_speed_mps; % exactly what it says it is
        F07_air_speed_mps; % exactly what it says it is
        F08_air_speed_mps; % exactly what it says it is
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % import files when the app starts
            data = load('fan_air_velocity.mat');
            vars = fieldnames(data);
            s = data.(vars{1});          % use the first variable stored in the MAT-file
            T = struct2table(s);
            app.F01_air_speed_mps = reshape(T{1:35:25305, "air_speed_mps"}, 1, []);
            app.F02_air_speed_mps = reshape(T{36:280:end, "air_speed_mps"}, 1, []);
            app.F03_air_speed_mps = reshape(T{71:280:end, "air_speed_mps"}, 1, []);
            app.F04_air_speed_mps = reshape(T{106:280:end, "air_speed_mps"}, 1, []);
            app.F05_air_speed_mps = reshape(T{141:280:end, "air_speed_mps"}, 1, []);
            app.F06_air_speed_mps = reshape(T{176:280:end, "air_speed_mps"}, 1, []);
            app.F07_air_speed_mps = reshape(T{211:280:end, "air_speed_mps"}, 1, []);
            app.F08_air_speed_mps = reshape(T{246:280:end, "air_speed_mps"}, 1, []);
            x         = linspace(8, 28/3, 723);
            app.UIAxes.XLim = [8, 28/3];
            plot(app.UIAxes, x, app.F01_air_speed_mps)
        end

        % Value changed function: ThemeDropDown
        function ThemeDropDownValueChanged(app, event)
            app_theme = app.ThemeDropDown.Value;
            % actions to take based on different values
            switch app_theme
                case "Light"              % Light mode
                    color1 = [0.96, 0.96, 0.96]; % very very very light gray
                    color2 = [0, 0, 0];          % pitch black
                case "Dark"               % Dark mode
                    color1 = [0.13, 0.13, 0.13]; % very dark gray
                    color2 = [0.96, 0.96, 0.96]; % very very very light gray
                case "Midnight"           % 'Dark-er' mode
                    color1 = [0, 0, 0];          % pitch black
                    color2 = [0.2, 0.86, 0.86];  % light cyan (white is too bright, plus it looks cool)
            end
            % Defines attributes needing to = color1 when theme is changed
            color1_targets = {
                app.UIFigure,               'Color';
                app.ThemeDropDown,          'BackgroundColor';
                app.FanDropDown,            'BackgroundColor';
                app.UIAxes,                 'Color'};

            % Defines attributes needing to = color2 when theme is changed
            color2_targets = {
                app.ThemeDropDown,          'FontColor';
                app.FanDropDown,            'FontColor';
                app.FanDropDownLabel,       'FontColor';
                app.ThemeDropDownLabel,     'FontColor';
                app.UIAxes,                 'GridColor';
                app.UIAxes,                 'MinorGridColor';
                app.UIAxes,                 'XColor';
                app.UIAxes,                 'YColor';
                app.UIAxes,                 'AmbientLightColor'};

            % Each attribute in color1_targets is changed to color1
            for i = 1:size(color1_targets,1)
                set(color1_targets{i,1}, color1_targets{i,2}, color1);
            end

            % Each attribute in color2_targets is changed to color2
            for i = 1:size(color2_targets,1)
                set(color2_targets{i,1}, color2_targets{i,2}, color2);
            end
        end

        % Button pushed function: settingsButton
        function settingsButtonPushed(app, event)

        end

        % Value changed function: FanDropDown
        function FanDropDownValueChanged(app, event)
            fan_dropdown_fan_id = app.FanDropDown.Value;
            x          = linspace(8, 20, 12); % 8, 20, 8640
            switch fan_dropdown_fan_id
                case 'F01'
                    app.UIAxes.XLim = [8, 11];
                    plot(app.UIAxes, x, F01_data1)
                case 'F02'
                    plot(app.UIAxes, x, F02_data1)
                case 'F03'
                    plot(app.UIAxes, x, F03_data1)
                case 'F04'
                    plot(app.UIAxes, x, F04_data1)
                case 'F05'
                    plot(app.UIAxes, x, F05_data1)
                case 'F06'
                    plot(app.UIAxes, x, F06_data1)
                case 'F07'
                    plot(app.UIAxes, x, F07_data1)
                case 'F08'
                    plot(app.UIAxes, x, F08_data1)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'v.s. Time')
            xlabel(app.UIAxes, 'Time (24h)')
            app.UIAxes.XLim = [8 20];
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.XTick = [8 11 14 17 20];
            app.UIAxes.XTickLabel = {'8h'; '11h'; '14h'; '17h'; '20h'};
            app.UIAxes.Toolbar = [];
            app.ThemeDropDownLabel = uilabel(app.UIFigure);
            app.ThemeDropDownLabel.Text = 'Theme';
            app.ThemeDropDown = uidropdown(app.UIFigure);
            app.ThemeDropDown.Items = {'Light', 'Dark', 'Midnight'};
            app.ThemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ThemeDropDownValueChanged, true);
            app.ThemeDropDown.Placeholder = 'Theme';
            app.ThemeDropDown.Value = 'Dark';
            app.settingsButton = uibutton(app.UIFigure, 'push');
            app.settingsButton.ButtonPushedFcn = createCallbackFcn(app, @settingsButtonPushed, true);
            app.settingsButton.Icon = fullfile(pathToMLAPP, 'vector-gear-settings-icon-11642664102rwrsyjsiwu-1184377603 (1).png');
            app.settingsButton.Text = '';
            app.FanDropDownLabel = uilabel(app.UIFigure);
            app.FanDropDownLabel.Text = 'Fan';
            app.FanDropDown = uidropdown(app.UIFigure);
            app.FanDropDown.Items = {'F01', 'F02', 'F03', 'F04', 'F05', 'F06', 'F07', 'F08'};
            app.FanDropDown.ValueChangedFcn = createCallbackFcn(app, @FanDropDownValueChanged, true);
            app.FanDropDown.Placeholder = 'Theme';
            app.FanDropDown.Value = 'F01';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end